classdef AdomeCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextArea                     matlab.ui.control.TextArea
        TestButton                   matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        TestcommandsListBox          matlab.ui.control.ListBox
        TestcommandsListBoxLabel     matlab.ui.control.Label
        ViewButton                   matlab.ui.control.Button
        AmountofLEDsEditField        matlab.ui.control.NumericEditField
        AmountofLEDsEditFieldLabel   matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        Lamp_3                       matlab.ui.control.Lamp
        Label_3                      matlab.ui.control.Label
        Lamp_2                       matlab.ui.control.Lamp
        Label_2                      matlab.ui.control.Label
        Lamp_1                       matlab.ui.control.Lamp
        Label                        matlab.ui.control.Label
        StartButton                  matlab.ui.control.StateButton
        ListofcoordinatesButton      matlab.ui.control.StateButton
        AmountofnodesEditField       matlab.ui.control.NumericEditField
        AmountofnodesEditFieldLabel  matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        calibrationCycle = 0 % 1st, 2nd or 3rd part of the cycle
        amountOfNodes = 1 % the amount of given nodes
        COMPort = "COM1"% The comport for OCS/Adome connection
        amountOfLeds = 3;
        numberOfTrials = 1 % the numer of trials OCS needs to perform
        viewMode = 1 % toggle between off, polar and 3D
        Polarax % Polar axes
        JSONData;
        decodedJSONData;
        antennaArray;
        timer1;
    end
    
    methods (Access = public)
        
        function startCalibrationCycle(app) %Start the calibration
            % parse info to OCS .mex file: 
            % COM port of ADome connection ("COM1") - Number of antennas in the ADome - 
            % Number of leds per antenna (3) - Number of trials
            JSONFILE_name= sprintf('Adome_Antennas.json');
%             if exist(JSONFILE_name, 'file')==2
%                 fid = fopen(JSONFILE_name,'w+'); %deletes contents 
%                 fclose(fid);
%             end
% 
%             mockJSON(app.COMPort, app.amountOfNodes, app.amountOfLeds, app.numberOfTrials);
            pause(2); %Pause the program for 2 seconds to mimic ocs delay

            fid = fopen(JSONFILE_name,'r');
            verifyContents = isempty(char(fread(fid))'); %Check if the json file has data in it
            if (verifyContents == true)
                disp('ERROR: json file is empty');
            elseif (verifyContents == false)
                app.endOfCalibrationCycle();
            end
        end

        function endOfCalibrationCycle(app) %End of calibration cycle
            if (app.calibrationCycle == 1)
                app.importJSONData('Adome_Antennas.json');
                app.decodedJSONData = jsondecode(app.JSONData);
                app.antennaArray = app.decodedJSONData;
                app.JSONExport();
                app.Lamp_1.Color = [0.00 1.00 0.00];
                app.StartButton.Text = 'Continue';
                app.StartButton.Enable = 'on';
            elseif(app.calibrationCycle == 2)
                app.importJSONData('Adome_Antennas.json');
                app.decodedJSONData = jsondecode(app.JSONData);
                app.overwriteFoundNodes();
                app.JSONExport();
                app.Lamp_2.Color = [0.00 1.00 0.00];
                app.StartButton.Text = 'Continue';
                app.StartButton.Enable = 'on';
            elseif(app.calibrationCycle == 3)
                app.importJSONData('Adome_Antennas.json');
                app.decodedJSONData = jsondecode(app.JSONData);
                app.overwriteFoundNodes();
                app.JSONExport();
                allNodesFound = nodeVerification(app.antennaArray);
                if (allNodesFound == true)
                    app.Lamp_3.Color = [0.00 1.00 0.00];
                    app.StartButton.Text = 'Start again?';
                elseif(allNodesFound == false)
                    app.Lamp_1.Color = [1.00 0.00 0.00];
                    app.Lamp_2.Color = [1.00 0.00 0.00];
                    app.Lamp_3.Color = [1.00 0.00 0.00];
                    app.StartButton.Text = 'Failed, retry?';
                end
                app.StartButton.Enable = 'on';
            end
        end
        
        function importJSONData(app, fileName) %Import and display the coordinates
            JSONFILE_name= sprintf(fileName);
            fid = fopen(JSONFILE_name,"r");
            app.JSONData = char(fread(fid))';
            fclose(fid);
        end
        
        function displayJSONData(app)
            app.UITable.Data = {};

            nodeColumn = 1;
            ledColumn = 2;
            foundColumn = 3;
            thetaColumn = 4;
            phiColumn = 5;
            xColumn = 6;
            yColumn = 7;
            zColumn = 8;
            a = 1;
            l = 1;
            antennaRow = 1;
            ledRow = 1;

            app.antennaArray = jsondecode(app.JSONData); %Turn to local variable to access struct
           
            while(app.antennaArray.Antennas(a).id <= app.antennaArray.Antennas(end).id)
                app.UITable.Data{antennaRow, nodeColumn} = app.antennaArray.Antennas(a).id;
                
                
                while(app.antennaArray.Antennas(a).leds(l).id <= app.antennaArray.Antennas(a).leds(end).id)
                    app.UITable.Data{ledRow, ledColumn} = app.antennaArray.Antennas(a).leds(l).id;
                    app.UITable.Data{ledRow, foundColumn} = app.antennaArray.Antennas(a).leds(l).found;
                    app.UITable.Data{ledRow, thetaColumn} = app.antennaArray.Antennas(a).leds(l).theta;
                    app.UITable.Data{ledRow, phiColumn} = app.antennaArray.Antennas(a).leds(l).phi;
                    
%                     if (app.antennaArray.Antennas(a).leds(l).found == false) %if the led is not found
%                         s = uistyle('BackgroundColor',[1.00 0.00 0.00]);%turn cell color red
%                         addStyle(app.UITable,s,'cell',[ledRow,foundColumn]);
%                     else
%                         s = uistyle('BackgroundColor',[0.00 0.00 0.00]);%turn cell color back to default
%                         addStyle(app.UITable,s,'cell',[ledRow,foundColumn]);
%                     end

                    lEnd = app.antennaArray.Antennas(a).leds(end).id;
                    if (l == lEnd) 
                        l = 1;
                        break;
                    else
                        l = l + 1;
                        ledRow = ledRow + 1;
                    end
                end
                
                aEnd = app.antennaArray.Antennas(end).id + 1;
                if (a == aEnd)
                    a = 1;
                    break;
                else 
                    a = a + 1;
                    antennaRow = antennaRow + app.amountOfLeds;
                    ledRow = antennaRow;
                end
            end
        end
        
        function overwriteFoundNodes(app)
            a = 1;
            l = 1;
            while(app.antennaArray.Antennas(a).id <= app.antennaArray.Antennas(end).id)             
                while(app.antennaArray.Antennas(a).leds(l).id <= app.antennaArray.Antennas(a).leds(end).id)
                    if(app.decodedJSONData.Antennas(a).leds(l).found == true)
                        app.antennaArray.Antennas(a).leds(l).found = app.decodedJSONData.Antennas(a).leds(l).found;
                        app.antennaArray.Antennas(a).leds(l).theta = app.decodedJSONData.Antennas(a).leds(l).theta;
                        app.antennaArray.Antennas(a).leds(l).phi = app.decodedJSONData.Antennas(a).leds(l).phi;
                    else
                        app.antennaArray.Antennas(a).leds(l).found = app.antennaArray.Antennas(a).leds(l).found;
                        app.antennaArray.Antennas(a).leds(l).theta = app.antennaArray.Antennas(a).leds(l).theta;
                        app.antennaArray.Antennas(a).leds(l).phi = app.antennaArray.Antennas(a).leds(l).phi;
                    end
                    
                    lEnd = app.antennaArray.Antennas(a).leds(end).id;
                    if (l == lEnd) 
                        l = 1;
                        break;
                    else
                        l = l + 1;
                    end
                end
                
                aEnd = app.antennaArray.Antennas(end).id + 1;
                if (a == aEnd)
                    a = 1;
                    break;
                else 
                    a = a + 1;
                end
            end
        end
        
        function JSONExport(app) %exports the overwritten JSON data
            JSONFILE_name= sprintf('Adome_Antennas_export.json');
            fid = fopen(JSONFILE_name, "w");
            JSON = jsonencode(app.antennaArray, PrettyPrint=true);
            fprintf(fid, JSON);
            fclose("all");
        end
    end
    
    methods (Access = private)
        
        function main(app, action)
%             run = false;
%             if (strcmp(action, 'run') == true)
%                 run = true;
%             elseif (strcmp(action, 'close') == true)
%                 run = false;
%             end
% 
%             seconds = 0;
%             minutes = 0;
%             hours = 0;
%             
%             while (run == true)
%                 fprintf('Runtime: %d hours %d minutes %d seconds.\n', hours, minutes, seconds)
%                 seconds = seconds + 1;
%                 if (seconds == 60)
%                     minutes = minutes + 1;
%                     if (minutes == 60)
%                         hours = hours + 1;
%                         minutes = 0;
%                     end
%                     seconds = 0;
%                 end
%                 pause(1);
%             end
        end
        
        function timerTriggered(app, event)
            %evalc()
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             t = timer('TimerFcn', "app.timerTriggered", 'StartDelay', 1, 'Period', 1, 'ExecutionMode', 'fixedRate');
            app.timer1 = timer();
            set(app.timer1, 'Period', 1);
            set(app.timer1, 'ExecutionMode', 'fixedRate');
%             set(t, 'StartFcn', @timerTriggered);
            set(app.timer1, 'TimerFcn', 'disp("cool")');
            disp(app.timer1)
            start(app.timer1)
%             app.main('run');
        end

        % Value changed function: AmountofnodesEditField
        function AmountofnodesEditFieldValueChanged(app, event)
            app.amountOfNodes = app.AmountofnodesEditField.Value;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            app.calibrationCycle = app.calibrationCycle + 1;
            app.StartButton.Text = 'Wait';
            app.StartButton.Enable = 'off';

            if (app.calibrationCycle == 1)
                app.Lamp_1.Color = [1.00 0.41 0.16];
            elseif(app.calibrationCycle == 2)
                app.Lamp_2.Color = [1.00 0.41 0.16];
            elseif(app.calibrationCycle == 3)
                app.Lamp_3.Color = [1.00 0.41 0.16];
            elseif(app.calibrationCycle == 4)
                app.Lamp_1.Color = [0.50 0.50 0.50];
                app.Lamp_2.Color = [0.50 0.50 0.50];
                app.Lamp_3.Color = [0.50 0.50 0.50];
                app.StartButton.Text = 'Start';
                app.StartButton.Enable = 'on';
                app.calibrationCycle = 0;

            end

            app.startCalibrationCycle();
        end

        % Value changed function: ListofcoordinatesButton
        function ListofcoordinatesButtonValueChanged(app, event)
            app.Polarax.Visible = 'off';
            app.UIAxes_2.Visible = 'off';
            
            if (app.UITable.Visible == "off")
                app.importJSONData('Adome_Antennas_export.json');
                app.displayJSONData();
                app.UITable.Visible = 'on';
            elseif(app.UITable.Visible == "on")
                app.UITable.Visible = 'off';
            end
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            app.UITable.Visible = 'off';
            app.viewMode = app.viewMode + 1;

            if (app.viewMode == 1)
                app.Polarax.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
            elseif(app.viewMode == 2)
                app.Polarax.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.Polarax = polaraxes(app.UIFigure);
                app.Polarax.Units = 'centimeters';
                app.Polarax.Position = [37 180 378 255];
            elseif(app.viewMode == 3)
                app.Polarax.Visible = 'off';
                app.UIAxes_2.Visible = 'on';
                app.viewMode = 0;
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            if (app.TestcommandsListBox.Value == "Import data")
                app.importJSONData();
            elseif (app.TestcommandsListBox.Value == "Serial write")
            elseif (app.TestcommandsListBox.Value == "Serial receive")    
            end
        end

        % Value changed function: AmountofLEDsEditField
        function AmountofLEDsEditFieldValueChanged(app, event)
            app.amountOfLeds = app.AmountofLEDsEditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             app.main('close')
            stop(app.timer1)
            delete(app.timer1)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '3D')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.AmountofnodesEditFieldLabel = uilabel(app.UIFigure);
            app.AmountofnodesEditFieldLabel.Text = 'Amount of nodes';
            app.AmountofnodesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountofnodesEditField.ValueChangedFcn = createCallbackFcn(app, @AmountofnodesEditFieldValueChanged, true);
            app.AmountofnodesEditField.Value = 1;
            app.ListofcoordinatesButton = uibutton(app.UIFigure, 'state');
            app.ListofcoordinatesButton.ValueChangedFcn = createCallbackFcn(app, @ListofcoordinatesButtonValueChanged, true);
            app.ListofcoordinatesButton.Text = 'List of coordinates';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Lamp_1 = uilamp(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Node'; 'LED'; 'Found'; 'Theta'; 'Phi'; 'x'; 'y'; 'z'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false];
            app.UITable.Visible = 'off';
            app.AmountofLEDsEditFieldLabel = uilabel(app.UIFigure);
            app.AmountofLEDsEditFieldLabel.Text = 'Amount of LED''s';
            app.AmountofLEDsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountofLEDsEditField.ValueChangedFcn = createCallbackFcn(app, @AmountofLEDsEditFieldValueChanged, true);
            app.AmountofLEDsEditField.Value = 3;
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.TestcommandsListBoxLabel = uilabel(app.UIFigure);
            app.TestcommandsListBoxLabel.Text = 'Test commands';
            app.TestcommandsListBox = uilistbox(app.UIFigure);
            app.TestcommandsListBox.Items = {'Import data', 'Serial write', 'Serial receive', 'Item 4'};
            app.TestcommandsListBox.Value = 'Import data';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdomeCalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end