classdef TestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        TextArea  matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function main(app)
            seconds = 0;
            minutes = 0;
            hours = 0;
            a = 1;
            start = 1;
            fid = fopen('AdomeGUIOutput.log', 'r');
            while isvalid(app)
                fprintf('Runtime: %d hours %d minutes %d seconds.\n', hours, minutes, seconds)
                fprintf('Test error')
                seconds = seconds + 1;
                if (seconds == 60)
                    minutes = minutes + 1;
                    if (minutes == 60)
                        hours = hours + 1;
                        minutes = 0;
                    end
                    seconds = 0;
                end
                
                
                check = fgetl(fid);
                while check ~= -1
                    if (a == start)
                        rawData{a,:} = check;
                    else
                        check = fgetl(fid);
                        if check == -1
                            rawData{a,:} = -a;
                            start = a + 1;
                        else
                            rawData{a,:} = check;
                        end
                    end
                    a = a + 1;
                end
                
                pause(1);
            end
        end
        
        function addTimestamp(app)
            d = char(datetime(now,'ConvertFrom','datenum'));
            currentDateTime = ['[', d, ']'];
            disp(currentDateTime)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             fid = fopen("AdomeGUIOutput.log", "w"); %clear file
%             fclose(fid);

%             diary AdomeGUIOutput.log
            app.addTimestamp();
            disp('GUI has started.')
            app.main();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            diary off
            delete(app)
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end