classdef TextboxTestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        TextArea  matlab.ui.control.TextArea
    end

    methods (Access = private)
        
        function main(app)
            seconds = 0;
            minutes = 0;
            hours = 0;
            a = 1;
            start = 1;
            
            fid = fopen('Diary/AdomeGUIOutput.log', 'r');
            while isvalid(app)
                check = fgetl(fid);
                while check ~= -1
                    if (a == start)
                        bug = strfind(check, '???'); % ??? needs to be defined
                        if (bug > 0)
                            rawData{a,:} = 'bug'; % replaces certain text in the output with bug
                        else
                            rawData{a,:} = check;
                        end                    
                    else
                        check = fgetl(fid);
                        bug = strfind(check, '???'); % ??? needs to be defined
                        if (check == -1)
                            rawData{a,:} = '#end#';
                            start = a + 1;
                            outputTextbox(app.TextArea, rawData, start);
                        else
                            if (bug > 0)
                                rawData{a,:} = 'bug'; % replaces certain text in the output with bug
                            else
                                rawData{a,:} = check;
                            end
                        end
                    end
                    a = a + 1;
                end
                pause(0.01);
            end
        end
        
        function addTimestamp(app)
            d = char(datetime(now,'ConvertFrom','datenum'));
            currentDateTime = ['[', d, ']'];
            disp(currentDateTime)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear outputTextbox %clear the persistent variables otherwise errors occur

            fid = fopen("Diary/AdomeGUIOutput.log", "w"); %clear file
            fclose(fid);
            diary Diary/AdomeGUIOutput.log
            app.addTimestamp();
            
            disp('GUI has started.')
            app.main();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            diary off
            delete(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            fprintf('test\n')
            fprintf('testing\n')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TextboxTestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end